classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResultadoLabel             matlab.ui.control.Label
        InicializaelprogramaLabel  matlab.ui.control.Label
        LocalizalaimagenLabel      matlab.ui.control.Label
        SeleccionalaIALabel        matlab.ui.control.Label
        ModelosListBox             matlab.ui.control.ListBox
        ModelosListBoxLabel        matlab.ui.control.Label
        EmpezarButton              matlab.ui.control.Button
        EtiquetaLabel              matlab.ui.control.Label
        ImagenButton               matlab.ui.control.Button
        UIAxes2_2                  matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        inImg % Imagen inicial
        finalImg % Imagen que se clasifica
        modelValue % Valor numérico del modelo usado
        model % Modelo usado
        YPred % Etiqueta predicha
        imgSel % Mira si se ha seleccionado una img
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Inicializamos las variables
            app.modelValue = "Simple";
            app.imgSel = 0;
            data = load("Modelos\simpleGalaxyNet_87acc.mat");
            app.model = data.trainedNet;
        end

        % Callback function
        function TakeimageButtonPushed(app, event)
            % No hace nada
        end

        % Button pushed function: ImagenButton
        function ImagenButtonPushed(app, event)
            % Selecciona una imagen del ordenador
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.imgSel = 1;
            
            % Cargo la imagen
            imds = imread(filename);
            app.inImg = imds;

            % Dependiendo del modelo, realizo un postprocesado u otro
            if app.modelValue == "Simple"
                app.finalImg = imcrop(app.inImg,[100, 100, 224, 224]);
                app.finalImg = imresize(app.finalImg,[224,224]);
            elseif app.modelValue == "Avanzado"
                app.finalImg = imcrop(app.inImg,[100, 100, 224, 224]);
                app.finalImg = imresize(app.finalImg,[224,224]);
            elseif app.modelValue == "Número"
                app.finalImg = im2gray(app.inImg);
                app.finalImg = imresize(app.finalImg,[20,20]);
            end
                
            % Muestro las imagenes
            imshow(app.finalImg,'Parent',app.UIAxes2);
            imshow(app.inImg,'Parent',app.UIAxes2_2);
            
        end

        % Callback function
        function HTMLDataChanged(app, event)

        end

        % Button pushed function: EmpezarButton
        function EmpezarButtonPushed(app, event)
            % Clasifico la imagen segun el modelo y la muestro por pantalla
            if app.modelValue == "Simple" || app.modelValue == "Avanzado"
                app.YPred = classify(app.model,app.finalImg);
            else
                mnistImg = cast(reshape(app.finalImg, 1, []),"double");
                vec = app.model.forward(mnistImg);
                [m,I] = max(vec);
                app.YPred = string(I-1);
            end         
            app.EtiquetaLabel.Text = app.YPred;
        end

        % Value changed function: ModelosListBox
        function ModelosListBoxValueChanged(app, event)
            value = app.ModelosListBox.Value;
            app.modelValue = value;
            
            % Cargo la red seleccionada
            if value == "Simple"
                data = load("Modelos\simpleGalaxyNet_87acc.mat");
                app.model = data.trainedNet;
            elseif value == "Avanzado"
                data = load("Modelos\massiveGalaxyNet_29acc.mat");
                app.model = data.trainedNet;
            elseif value == "Número"
                data = load("Modelos\MNIST_74acc.mat");
                app.model = data.numNet;
            end

            % Dependiendo del modelo, realizo un postprocesado u otro   
            if app.imgSel == 1
                if app.modelValue == "Simple"
                    app.finalImg = imcrop(app.inImg,[100, 100, 224, 224]);
                    app.finalImg = imresize(app.finalImg,[224,224]);
                elseif app.modelValue == "Avanzado"
                    app.finalImg = imcrop(app.inImg,[100, 100, 224, 224]);
                    app.finalImg = imresize(app.finalImg,[224,224]);
                elseif app.modelValue == "Número"
                    app.finalImg = im2gray(app.inImg);
                    app.finalImg = imresize(app.finalImg,[20,20]);
                end
                    
                % Muestro las imagenes
                imshow(app.finalImg,'Parent',app.UIAxes2);
                imshow(app.inImg,'Parent',app.UIAxes2_2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Imagen Preprocesada')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            colormap(app.UIAxes2, 'turbo')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Imagen Seleccionada')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            colormap(app.UIAxes2_2, 'turbo')
            app.ImagenButton = uibutton(app.UIFigure, 'push');
            app.ImagenButton.ButtonPushedFcn = createCallbackFcn(app, @ImagenButtonPushed, true);
            app.ImagenButton.Text = 'Imagen';
            app.EtiquetaLabel = uilabel(app.UIFigure);
            app.EtiquetaLabel.Text = 'Etiqueta';
            app.EmpezarButton = uibutton(app.UIFigure, 'push');
            app.EmpezarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezarButtonPushed, true);
            app.EmpezarButton.Text = 'Empezar';
            app.ModelosListBoxLabel = uilabel(app.UIFigure);
            app.ModelosListBoxLabel.Text = 'Modelos';
            app.ModelosListBox = uilistbox(app.UIFigure);
            app.ModelosListBox.Items = {'Simple', 'Avanzado', 'Número'};
            app.ModelosListBox.ValueChangedFcn = createCallbackFcn(app, @ModelosListBoxValueChanged, true);
            app.ModelosListBox.Value = 'Simple';
            app.SeleccionalaIALabel = uilabel(app.UIFigure);
            app.SeleccionalaIALabel.Text = '1. Selecciona la IA';
            app.LocalizalaimagenLabel = uilabel(app.UIFigure);
            app.LocalizalaimagenLabel.Text = '2. Localiza la imagen';
            app.InicializaelprogramaLabel = uilabel(app.UIFigure);
            app.InicializaelprogramaLabel.Text = '3. Inicializa el programa';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = '4. Resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end